classdef Filter_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WeightsEditField              matlab.ui.control.EditField
        WeightsEditFieldLabel         matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Cutoff_freqEditField          matlab.ui.control.NumericEditField
        Cutoff_freqEditFieldLabel     matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        DesiredPointsinFreqEditField  matlab.ui.control.NumericEditField
        DesiredPointsinFreqEditFieldLabel  matlab.ui.control.Label
        PlotingScaleDropDown          matlab.ui.control.DropDown
        PlotingScaleDropDownLabel     matlab.ui.control.Label
        FilterLengthEditField         matlab.ui.control.NumericEditField
        FilterLengthEditFieldLabel    matlab.ui.control.Label
        WindowTypeDropDown            matlab.ui.control.DropDown
        WindowTypeDropDownLabel       matlab.ui.control.Label
        filterdesignmethodDropDown    matlab.ui.control.DropDown
        filterdesignmethodDropDownLabel  matlab.ui.control.Label
        FilterDesignToolLabel         matlab.ui.control.Label
    end

    
    methods (Access = private)
        
       function numericArray = validateCommaSepInput(app, inputValue, fieldName)
            if ischar(inputValue) || isstring(inputValue)
                numericArray = str2double(split(inputValue, ','));
            else
                numericArray = inputValue;
            end
        
            if isempty(numericArray) || any(isnan(numericArray)) || ~isnumeric(numericArray)
                numericArray = -1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            method = app.filterdesignmethodDropDown.Value;
            length = app.FilterLengthEditField.Value;
            window = app.WindowTypeDropDown.Value;
            Desired_Points = app.DesiredPointsinFreqEditField.Value;
            Scale = app.PlotingScaleDropDown.Value;
            cutoff = app.Cutoff_freqEditField.Value;
            weights = validateCommaSepInput(app, app.WeightsEditField.Value, 'weights');
            
            % Initialize an error message string
            errorMessage = '';
            
            if strcmp(method, "Windowed sinc")
                if isempty(method)
                    errorMessage = 'Method must not be null.';
                elseif isempty(length)
                    errorMessage = 'Length must not be null.';
                elseif isempty(window)
                    errorMessage = 'Window must not be null.';
                elseif isempty(Scale)
                    errorMessage = 'Scale must not be null.';
                elseif isempty(cutoff)
                    errorMessage = 'Cutoff must not be null.';
                end
            elseif strcmp(method, "LS")
                if isempty(method)
                    errorMessage = 'Method must not be null.';
                elseif isempty(length)
                    errorMessage = 'Length must not be null.';
                elseif isempty(Scale)
                    errorMessage = 'Scale must not be null.';
                elseif isempty(cutoff)
                    errorMessage = 'Cutoff must not be null.';
                elseif isempty(Desired_Points)
                    errorMessage = 'Desired Points must not be null.';
                end
            elseif strcmp(method, "WLC")
                if isempty(method)
                    errorMessage = 'Method must not be null.';
                elseif isempty(length)
                    errorMessage = 'Length must not be null.';
                elseif isempty(Scale)
                    errorMessage = 'Scale must not be null.';
                elseif isempty(cutoff)
                    errorMessage = 'Cutoff must not be null.';
                elseif isempty(Desired_Points)
                    errorMessage = 'Desired Points must not be null.';
                elseif isempty(weights)
                    errorMessage = 'Weights must not be null.';
                
                elseif weights == -1
                    errorMessage = 'Weights must be comma separeted numbers';
                end
            else
                errorMessage = 'Invalid value selected.';
            end
            
            if ~isempty(errorMessage)
                app.Label.Visible = 'on';
                app.Label.Text = errorMessage;
            else
                app.Label.Visible = 'off';
            end


            Main(method, length,window, Desired_Points, weights, Scale, cutoff)

        end

        % Value changed function: filterdesignmethodDropDown
        function filter_design_method_change(app, event)
            value = app.filterdesignmethodDropDown.Value;
            if value == "Windowed sinc"
                app.WindowTypeDropDown.Visible = 'on';
                app.WindowTypeDropDown.Enable = 'on';
                app.WindowTypeDropDownLabel.Visible = 'on';
                app.WindowTypeDropDownLabel.Enable = 'on';
                app.DesiredPointsinFreqEditField.Visible = 'off';
                app.DesiredPointsinFreqEditField.Enable = 'off';
                app.DesiredPointsinFreqEditFieldLabel.Visible = 'off';
                app.DesiredPointsinFreqEditFieldLabel.Enable = 'off';
                app.WeightsEditField.Visible = 'off';
                app.WeightsEditField.Enable= 'off';
                app.WeightsEditFieldLabel.Visible = 'off';
                app.WeightsEditFieldLabel.Enable = 'off';
            elseif value == "LS"
                app.WindowTypeDropDown.Visible = 'off';
                app.WindowTypeDropDown.Enable = 'off';
                app.WindowTypeDropDownLabel.Visible = 'off';
                app.WindowTypeDropDownLabel.Enable = 'off';
                app.DesiredPointsinFreqEditField.Visible = 'on';
                app.DesiredPointsinFreqEditField.Enable = 'on';
                app.DesiredPointsinFreqEditFieldLabel.Visible = 'on';
                app.DesiredPointsinFreqEditFieldLabel.Enable = 'on';
                app.WeightsEditField.Visible = 'off';
                app.WeightsEditField.Enable= 'off';
                app.WeightsEditFieldLabel.Visible = 'off';
                app.WeightsEditFieldLabel.Enable = 'off';
            elseif value == "WLC"
                app.WindowTypeDropDown.Visible = 'off';
                app.WindowTypeDropDown.Enable = 'off';
                app.WindowTypeDropDownLabel.Visible = 'off';
                app.WindowTypeDropDownLabel.Enable = 'off';
                app.DesiredPointsinFreqEditField.Visible = 'on';
                app.DesiredPointsinFreqEditField.Enable = 'on';
                app.DesiredPointsinFreqEditFieldLabel.Visible = 'on';
                app.DesiredPointsinFreqEditFieldLabel.Enable = 'on';
                app.WeightsEditField.Visible = 'on';
                app.WeightsEditField.Enable= 'on';
                app.WeightsEditFieldLabel.Visible = 'on';
                app.WeightsEditFieldLabel.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilterDesignToolLabel = uilabel(app.UIFigure);
            app.FilterDesignToolLabel.Text = 'Filter Design Tool';
            app.filterdesignmethodDropDownLabel = uilabel(app.UIFigure);
            app.filterdesignmethodDropDownLabel.Text = 'filter design method.';
            app.filterdesignmethodDropDown = uidropdown(app.UIFigure);
            app.filterdesignmethodDropDown.Items = {'Windowed sinc', 'LS', 'WLC'};
            app.filterdesignmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @filter_design_method_change, true);
            app.filterdesignmethodDropDown.Value = 'Windowed sinc';
            app.WindowTypeDropDownLabel = uilabel(app.UIFigure);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.UIFigure);
            app.WindowTypeDropDown.Items = {'Rectangular', 'Blackman', 'Chebyshev', 'Kaiser'};
            app.WindowTypeDropDown.Value = 'Rectangular';
            app.FilterLengthEditFieldLabel = uilabel(app.UIFigure);
            app.FilterLengthEditFieldLabel.Text = 'Filter Length';
            app.FilterLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotingScaleDropDownLabel = uilabel(app.UIFigure);
            app.PlotingScaleDropDownLabel.Text = 'Ploting Scale';
            app.PlotingScaleDropDown = uidropdown(app.UIFigure);
            app.PlotingScaleDropDown.Items = {'Linear Scale', 'Log Scale'};
            app.PlotingScaleDropDown.Value = 'Linear Scale';
            app.DesiredPointsinFreqEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredPointsinFreqEditFieldLabel.Enable = 'off';
            app.DesiredPointsinFreqEditFieldLabel.Visible = 'off';
            app.DesiredPointsinFreqEditFieldLabel.Text = 'Desired Points in Freq';
            app.DesiredPointsinFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredPointsinFreqEditField.Enable = 'off';
            app.DesiredPointsinFreqEditField.Visible = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Cutoff_freqEditFieldLabel = uilabel(app.UIFigure);
            app.Cutoff_freqEditFieldLabel.Text = 'Cutoff_freq';
            app.Cutoff_freqEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Visible = 'off';
            app.WeightsEditFieldLabel = uilabel(app.UIFigure);
            app.WeightsEditFieldLabel.Enable = 'off';
            app.WeightsEditFieldLabel.Visible = 'off';
            app.WeightsEditFieldLabel.Text = 'Weights';
            app.WeightsEditField = uieditfield(app.UIFigure, 'text');
            app.WeightsEditField.Enable = 'off';
            app.WeightsEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filter_Tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end